classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        LoadDataTab                     matlab.ui.container.Tab
        FileNameEditFieldLabel          matlab.ui.control.Label
        FileNameEditField               matlab.ui.control.EditField
        BoardsTab                       matlab.ui.container.Tab
        GSEControllerPanel_2            matlab.ui.container.Panel
        ValveChannelsTextArea_2         matlab.ui.control.TextArea
        ValveChannelsTextArea_2Label    matlab.ui.control.Label
        PTChannelsTextArea_2            matlab.ui.control.TextArea
        PTChannelsTextArea_2Label       matlab.ui.control.Label
        EngineControllerPanel_2         matlab.ui.container.Panel
        PTChannelsTextAreaLabel         matlab.ui.control.Label
        PTChannelsTextArea              matlab.ui.control.TextArea
        ValveChannelsTextArea           matlab.ui.control.TextArea
        ValveChannelsTextAreaLabel      matlab.ui.control.Label
        PlotsTab                        matlab.ui.container.Tab
        BoardsLabel                     matlab.ui.control.Label
        GSEControllerCheckBox           matlab.ui.control.CheckBox
        EngineControllerCheckBox        matlab.ui.control.CheckBox
        GSEControllerPanel              matlab.ui.container.Panel
        PTsLabel                        matlab.ui.control.Label
        ValvesLabel                     matlab.ui.control.Label
        CheckBox_9                      matlab.ui.control.CheckBox
        CheckBox_10                     matlab.ui.control.CheckBox
        CheckBox_11                     matlab.ui.control.CheckBox
        CheckBox_12                     matlab.ui.control.CheckBox
        CheckBox_13                     matlab.ui.control.CheckBox
        CheckBox_14                     matlab.ui.control.CheckBox
        CheckBox_15                     matlab.ui.control.CheckBox
        CheckBox_16                     matlab.ui.control.CheckBox
        CheckBox_17                     matlab.ui.control.CheckBox
        CheckBox_18                     matlab.ui.control.CheckBox
        CheckBox_19                     matlab.ui.control.CheckBox
        CheckBox_20                     matlab.ui.control.CheckBox
        CheckBox_21                     matlab.ui.control.CheckBox
        CheckBox_22                     matlab.ui.control.CheckBox
        CheckBox_23                     matlab.ui.control.CheckBox
        CheckBox_24                     matlab.ui.control.CheckBox
        CheckBox_25                     matlab.ui.control.CheckBox
        CheckBox_26                     matlab.ui.control.CheckBox
        CheckBox_27                     matlab.ui.control.CheckBox
        CheckBox_28                     matlab.ui.control.CheckBox
        CheckBox_29                     matlab.ui.control.CheckBox
        CheckBox_30                     matlab.ui.control.CheckBox
        CheckBox_31                     matlab.ui.control.CheckBox
        CheckBox_32                     matlab.ui.control.CheckBox
        LoadCellsLabel                  matlab.ui.control.Label
        EngineControllerPanel           matlab.ui.container.Panel
        PTsLabel_2                      matlab.ui.control.Label
        ValvesLabel_2                   matlab.ui.control.Label
        CheckBox_33                     matlab.ui.control.CheckBox
        CheckBox_34                     matlab.ui.control.CheckBox
        CheckBox_35                     matlab.ui.control.CheckBox
        CheckBox_36                     matlab.ui.control.CheckBox
        CheckBox_37                     matlab.ui.control.CheckBox
        CheckBox_38                     matlab.ui.control.CheckBox
        CheckBox_39                     matlab.ui.control.CheckBox
        CheckBox_40                     matlab.ui.control.CheckBox
        CheckBox_41                     matlab.ui.control.CheckBox
        CheckBox_42                     matlab.ui.control.CheckBox
        CheckBox_43                     matlab.ui.control.CheckBox
        CheckBox_44                     matlab.ui.control.CheckBox
        CheckBox_45                     matlab.ui.control.CheckBox
        CheckBox_46                     matlab.ui.control.CheckBox
        CheckBox_47                     matlab.ui.control.CheckBox
        CheckBox_48                     matlab.ui.control.CheckBox
        CheckBox_49                     matlab.ui.control.CheckBox
        CheckBox_50                     matlab.ui.control.CheckBox
        CheckBox_51                     matlab.ui.control.CheckBox
        CheckBox_52                     matlab.ui.control.CheckBox
        CheckBox_53                     matlab.ui.control.CheckBox
        CheckBox_54                     matlab.ui.control.CheckBox
        CheckBox_55                     matlab.ui.control.CheckBox
        CheckBox_56                     matlab.ui.control.CheckBox
        LoadCellsLabel_2                matlab.ui.control.Label
        RunButton                       matlab.ui.control.Button
        SavePlotsButton                 matlab.ui.control.Button
        CopyplotButton                  matlab.ui.control.Button
        closeallButton                  matlab.ui.control.Button
        NewFigureEditFieldLabel         matlab.ui.control.Label
        NewFigureEditField              matlab.ui.control.EditField
        ListBoxLabel                    matlab.ui.control.Label
        ListBox                         matlab.ui.control.ListBox
        ClosefigureButton               matlab.ui.control.Button
        PostprocessingTab               matlab.ui.container.Tab
        LegendCheckBox                  matlab.ui.control.CheckBox
        ColorSchemeDropDownLabel        matlab.ui.control.Label
        ColorSchemeDropDown             matlab.ui.control.DropDown
        LineThicknessEditFieldLabel     matlab.ui.control.Label
        LineThicknessEditField          matlab.ui.control.EditField
        LineSymbolsEditFieldLabel       matlab.ui.control.Label
        LineSymbolsEditField            matlab.ui.control.EditField
        TitleEditFieldLabel             matlab.ui.control.Label
        TitleEditField                  matlab.ui.control.EditField
        xlabelEditFieldLabel            matlab.ui.control.Label
        xlabelEditField                 matlab.ui.control.EditField
        ylabelEditFieldLabel            matlab.ui.control.Label
        ylabelEditField                 matlab.ui.control.EditField
        ylimEditFieldLabel              matlab.ui.control.Label
        ylimEditField                   matlab.ui.control.EditField
        xlimEditFieldLabel              matlab.ui.control.Label
        xlimEditField                   matlab.ui.control.EditField
        SettingsTab                     matlab.ui.container.Tab
        ECPTprefixEditFieldLabel        matlab.ui.control.Label
        ECPTprefixEditField             matlab.ui.control.EditField
        GSEControllerPTprefixEditFieldLabel  matlab.ui.control.Label
        GSEControllerPTprefixEditField  matlab.ui.control.EditField
        GSEControllerPTsuffixEditFieldLabel  matlab.ui.control.Label
        GSEControllerPTsuffixEditField  matlab.ui.control.EditField
        ECPTsuffixEditFieldLabel        matlab.ui.control.Label
        ECPTsuffixEditField             matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startApp(app)
            clear global;
            close all;
            
            % Create options parameters
            global options;
            options.num_figures = 0;
            options.figure = '';
        end

        % Callback function
        function BoardSelected(app, event)
            value = app.SelectedBoardDropDown.Value;
            
            switch value
                case "Select Board"
                    app.GSEControllerPanel_2.Visible = 0;
                    app.EngineControllerPanel_2.Visible = 0;
                case "GSE Controller"
                    app.GSEControllerPanel_2.Visible = 1;
                    app.EngineControllerPanel_2.Visible = 0;
                case "Engine Controller"
                    app.GSEControllerPanel_2.Visible = 0;
                    app.EngineControllerPanel_2.Visible = 1;
            end
        end

        % Callback function
        function FigureNames(app, event)
            global options
            
            value = app.FigureNamesTextArea.Value;
%             app.SelectFigureDropDown.Items{2:end} = value;
            
            for i = 1:length(value)
                app.SelectFigureDropDown.Items{i+1} = value{i};
                options.figures(i) = value(i);
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            global options
            global inputs
            
            % Prefixes and Suffixes
            options.engine_controller.pts.prefix = 'press_pressure_';
            options.engine_controller.pts.suffix = '__psi_';

            options.engine_controller.valves.prefix = 'press_vlv';
            options.engine_controller.valves.suffix = '_en_ul_';
            
            % Read in data
            options.data = readtable(app.FileNameEditField.Value);
            options.data_length = length(table2array(options.data(:,1)));

            % Convert inputs to options
            options.engine_controller.pts.name = inputs.engine_controller.pts.name;
            options.engine_controller.pts.number = inputs.engine_controller.pts.number;
            
            % If no figure was created, make one
%             if isempty(options.figure)
%                 app.NewFigureEditField.Value = num2str(options.num_figures+1);
%                 app.NewFigureEditFieldValueChanged;
%             end

            for j = 1:options.num_figures
                if j<=length(options.figures)
                    if isempty(options.figure)
                        figure('Name', char(options.figures(j)));
                    else
                        figure(gcf);
                    end
                    hold(gca, 'on');
                    for i = 1:length(options.engine_controller.pts.number)
                        plot(gca, 1:options.data_length, table2array(options.data(:,string(options.engine_controller.pts.prefix+string(num2str(options.engine_controller.pts.number(i)))+options.engine_controller.pts.suffix))),'displayname',options.engine_controller.pts.name{i});
                    end
                    legend(gca);
                else
                    figure('Name', string(j));
                end
            end
            
            % Update gcf
            items = app.ListBox.Items;
            app.ListBox.Value = items{options.num_figures+1};
            app.ListBoxValueChanged;

        end

        % Value changed function: PTChannelsTextArea
        function EC_PT_Text_Changed(app, event)
            value = app.PTChannelsTextArea.Value;
            global inputs;
            for i = 1:length(value)
                row = strsplit(value{i});
                inputs.engine_controller.pts.number(i) = str2double(row(1));
                inputs.engine_controller.pts.name(i) = {[row{2:end}]};
            end
        end

        % Button pushed function: SavePlotsButton
        function savePlots(app, event)
            if exist('Plots', 'dir')
                rmdir('Plots', 's')
            end
            
            mkdir('Plots');
            mkdir('Plots/fig');
            mkdir('Plots/png');
            mkdir('Plots/pdf');
                        
            allAxes = findall(groot,'Type','Axes');
            for i = 1 : length(allAxes)
                fignew = figure('Visible','off'); % Invisible figure
                newAxes = copyobj(allAxes(i),fignew); % Copy the appropriate axes
                set(newAxes,'Position',get(groot,'DefaultAxesPosition')); % The original position is copied too, so adjust it.
                set(fignew,'CreateFcn','set(gcbf,''Visible'',''on'')'); % Make it visible upon loading
                legend(newAxes);
                
                filename = join(fignew.CurrentAxes.Title.String);
                fignew.CurrentAxes.Title.Visible = 'off';
                filename = regexprep(filename, ' ', '_');      % replaces spaces with underscores
                filename = regexprep(filename, '[.]', '');      % replaces periods with nothing
                savefig(fignew,string(join(['Plots/fig/' filename], '')),'compact'); 
                print(fignew,string(join(['Plots/png/', filename, '.png'],'')),'-dpng'); 
                set(fignew,'Units','Inches');
                pos = get(fignew,'Position');
                set(fignew,'PaperPositionMode','Auto','PaperUnits','Inches','PaperSize',[pos(3), pos(4)])
                print(fignew,string(join(['Plots/pdf/', filename, '.pdf'],'')),'-dpdf');
                close(fignew);
            end

        end

        % Button pushed function: CopyplotButton
        function copyPlots(app, event)
            allAxes = findall(groot,'Type','Axes');
            for i = 1 : length(allAxes)
                fignew = figure('Visible','off'); % Invisible figure
                newAxes = copyobj(allAxes(i),fignew); % Copy the appropriate axes
                set(newAxes,'Position',get(groot,'DefaultAxesPosition')); % The original position is copied too, so adjust it.
                set(fignew,'Visible','on'); % Make it visible upon loading
                legend(newAxes);
            end
        end

        % Button pushed function: closeallButton
        function closeall(app, event)
            close all;
            
            global options
            options.figure = '';
            
            app.SelectFigureDropDown.Items = {'Select Figure'};
        end

        % Value changed function: ValveChannelsTextArea
        function EC_Valve_Text_Changed(app, event)
            value = app.ValveChannelsTextArea.Value;
            global inputs;
            for i = 1:length(value)
                row = strsplit(value{i});
                inputs.engine_controller.valves.number(i) = str2double(row(1));
                inputs.engine_controller.valves.name(i) = {[row{2:end}]};
            end

        end

        % Value changed function: ECPTprefixEditField
        function ECPTprefixEditFieldValueChanged(app, event)
            value = app.ECPTprefixEditField.Value;
            global options;
            options.engine_controller.pts.prefix = value;
        end

        % Value changed function: ECPTsuffixEditField
        function ECPTsuffixEditFieldValueChanged(app, event)
            value = app.ECPTsuffixEditField.Value;
            global options;
            options.engine_controller.pts.suffix = value;
        end

        % Callback function
        function SelectFigureDropDown_3ValueChanged(app, event)
            
        end

        % Value changing function: TitleEditField
        function TitleEditFieldValueChanging(app, event)
            changingValue = event.Value;
            title(gca, changingValue);
        end

        % Callback function
        function SelectFigureDropDownValueChanged(app, event)
            value = app.SelectFigureDropDown.Value;
            
            global options;
            index = find(contains(options.figures, value));
            set(0, 'CurrentFigure', index);
            
            options.figure = value;
        end

        % Callback function
        function numberOfFiguresChanged(app, event)
            value = app.NumberofFiguresEditField.Value;
            
            global options;
            options.num_figures = value;
        end

        % Value changed function: CheckBox_9
        function CheckBox_9ValueChanged(app, event)
            value = app.CheckBox_9.Value;
            
            axesObjs = get(gcf, 'Children');
            lineObjs = findobj(axesObjs, 'type', 'line');
            
            global options
            index = find(options.engine_controller.pts.number==1);
            lineObjs = flip(lineObjs);
            
            if value
                set(lineObjs(index), 'visible', 'on');
            else
                set(lineObjs(index), 'visible', 'off');
            end
        end

        % Value changed function: NewFigureEditField
        function NewFigureEditFieldValueChanged(app, event)
            value = app.NewFigureEditField.Value;
            global options
                        
            app.ListBox.Items{end+1} = value;
            if app.RunButton.Visible == 0
                app.RunButton.Visible = 1;
                app.SavePlotsButton.Visible = 1;
                app.CopyplotButton.Visible = 1;
                app.closeallButton.Visible = 1;
            end
            
            % Create new figure
            options.figures(1 + options.num_figures) = {value};
            options.num_figures = options.num_figures + 1;
            options.figure = value;
            figure('Name', char(options.figures(end)));
            
            % Update List Box to new figure
            app.ListBox.Value = value;
            app.ListBoxValueChanged;

            % Wipe New Figure Edit Field
            app.NewFigureEditField.Value = '';
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
            
            global options;
            index = find(contains(options.figures, value));
            set(0, 'CurrentFigure', index);
            
            options.figure = value;
        end

        % Button pushed function: ClosefigureButton
        function closeCurrentFigure(app, event)
            global options;
            options.figure = '';
            
            
            close(gcf);
        end

        % Value changing function: NewFigureEditField
        function NewFigureEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LoadDataTab = uitab(app.TabGroup);
            app.LoadDataTab.Title = 'Load Data';
            app.FileNameEditFieldLabel = uilabel(app.LoadDataTab);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.FileNameEditField = uieditfield(app.LoadDataTab, 'text');
            app.FileNameEditField.Value = '20210824184746_presstest1_data_log';
            app.BoardsTab = uitab(app.TabGroup);
            app.BoardsTab.Title = 'Boards';
            app.GSEControllerPanel_2 = uipanel(app.BoardsTab);
            app.GSEControllerPanel_2.Title = 'GSE Controller';
            app.ValveChannelsTextArea_2 = uitextarea(app.GSEControllerPanel_2);
            app.ValveChannelsTextArea_2Label = uilabel(app.GSEControllerPanel_2);
            app.ValveChannelsTextArea_2Label.Text = 'Valve Channels';
            app.PTChannelsTextArea_2 = uitextarea(app.GSEControllerPanel_2);
            app.PTChannelsTextArea_2Label = uilabel(app.GSEControllerPanel_2);
            app.PTChannelsTextArea_2Label.Text = 'PT Channels';
            app.EngineControllerPanel_2 = uipanel(app.BoardsTab);
            app.EngineControllerPanel_2.Title = 'Engine Controller';
            app.PTChannelsTextAreaLabel = uilabel(app.EngineControllerPanel_2);
            app.PTChannelsTextAreaLabel.Text = 'PT Channels';
            app.PTChannelsTextArea = uitextarea(app.EngineControllerPanel_2);
            app.PTChannelsTextArea.ValueChangedFcn = createCallbackFcn(app, @EC_PT_Text_Changed, true);
            app.PTChannelsTextArea.Value = {'2 who'; '1 what'};
            app.ValveChannelsTextArea = uitextarea(app.EngineControllerPanel_2);
            app.ValveChannelsTextArea.ValueChangedFcn = createCallbackFcn(app, @EC_Valve_Text_Changed, true);
            app.ValveChannelsTextAreaLabel = uilabel(app.EngineControllerPanel_2);
            app.ValveChannelsTextAreaLabel.Text = 'Valve Channels';
            app.PlotsTab = uitab(app.TabGroup);
            app.PlotsTab.Title = 'Plots';
            app.BoardsLabel = uilabel(app.PlotsTab);
            app.BoardsLabel.Text = 'Boards:';
            app.GSEControllerCheckBox = uicheckbox(app.PlotsTab);
            app.GSEControllerCheckBox.Text = 'GSE Controller';
            app.EngineControllerCheckBox = uicheckbox(app.PlotsTab);
            app.EngineControllerCheckBox.Text = 'Engine Controller';
            app.GSEControllerPanel = uipanel(app.PlotsTab);
            app.GSEControllerPanel.Title = 'GSE Controller';
            app.PTsLabel = uilabel(app.GSEControllerPanel);
            app.PTsLabel.Text = 'PTs';
            app.ValvesLabel = uilabel(app.GSEControllerPanel);
            app.ValvesLabel.Text = 'Valves';
            app.CheckBox_9 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_9.ValueChangedFcn = createCallbackFcn(app, @CheckBox_9ValueChanged, true);
            app.CheckBox_9.Text = '1';
            app.CheckBox_9.Value = true;
            app.CheckBox_10 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_10.Text = '2';
            app.CheckBox_10.Value = true;
            app.CheckBox_11 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_11.Text = '4';
            app.CheckBox_11.Value = true;
            app.CheckBox_12 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_12.Text = '3';
            app.CheckBox_12.Value = true;
            app.CheckBox_13 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_13.Text = '8';
            app.CheckBox_13.Value = true;
            app.CheckBox_14 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_14.Text = '7';
            app.CheckBox_14.Value = true;
            app.CheckBox_15 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_15.Text = '6';
            app.CheckBox_15.Value = true;
            app.CheckBox_16 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_16.Text = '5';
            app.CheckBox_16.Value = true;
            app.CheckBox_17 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_17.Text = '8';
            app.CheckBox_17.Value = true;
            app.CheckBox_18 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_18.Text = '7';
            app.CheckBox_18.Value = true;
            app.CheckBox_19 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_19.Text = '6';
            app.CheckBox_19.Value = true;
            app.CheckBox_20 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_20.Text = '5';
            app.CheckBox_20.Value = true;
            app.CheckBox_21 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_21.Text = '4';
            app.CheckBox_21.Value = true;
            app.CheckBox_22 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_22.Text = '3';
            app.CheckBox_22.Value = true;
            app.CheckBox_23 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_23.Text = '2';
            app.CheckBox_23.Value = true;
            app.CheckBox_24 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_24.Text = '1';
            app.CheckBox_24.Value = true;
            app.CheckBox_25 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_25.Text = '8';
            app.CheckBox_25.Value = true;
            app.CheckBox_26 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_26.Text = '7';
            app.CheckBox_26.Value = true;
            app.CheckBox_27 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_27.Text = '6';
            app.CheckBox_27.Value = true;
            app.CheckBox_28 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_28.Text = '5';
            app.CheckBox_28.Value = true;
            app.CheckBox_29 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_29.Text = '4';
            app.CheckBox_29.Value = true;
            app.CheckBox_30 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_30.Text = '3';
            app.CheckBox_30.Value = true;
            app.CheckBox_31 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_31.Text = '2';
            app.CheckBox_31.Value = true;
            app.CheckBox_32 = uicheckbox(app.GSEControllerPanel);
            app.CheckBox_32.Text = '1';
            app.CheckBox_32.Value = true;
            app.LoadCellsLabel = uilabel(app.GSEControllerPanel);
            app.LoadCellsLabel.Text = 'Load Cells';
            app.EngineControllerPanel = uipanel(app.PlotsTab);
            app.EngineControllerPanel.Title = 'Engine Controller';
            app.PTsLabel_2 = uilabel(app.EngineControllerPanel);
            app.PTsLabel_2.Text = 'PTs';
            app.ValvesLabel_2 = uilabel(app.EngineControllerPanel);
            app.ValvesLabel_2.Text = 'Valves';
            app.CheckBox_33 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_33.Text = '1';
            app.CheckBox_34 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_34.Text = '2';
            app.CheckBox_35 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_35.Text = '4';
            app.CheckBox_36 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_36.Text = '3';
            app.CheckBox_37 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_37.Text = '8';
            app.CheckBox_38 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_38.Text = '7';
            app.CheckBox_39 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_39.Text = '6';
            app.CheckBox_40 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_40.Text = '5';
            app.CheckBox_41 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_41.Text = '8';
            app.CheckBox_42 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_42.Text = '7';
            app.CheckBox_43 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_43.Text = '6';
            app.CheckBox_44 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_44.Text = '5';
            app.CheckBox_45 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_45.Text = '4';
            app.CheckBox_46 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_46.Text = '3';
            app.CheckBox_47 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_47.Text = '2';
            app.CheckBox_48 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_48.Text = '1';
            app.CheckBox_49 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_49.Text = '8';
            app.CheckBox_50 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_50.Text = '7';
            app.CheckBox_51 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_51.Text = '6';
            app.CheckBox_52 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_52.Text = '5';
            app.CheckBox_53 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_53.Text = '4';
            app.CheckBox_54 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_54.Text = '3';
            app.CheckBox_55 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_55.Text = '2';
            app.CheckBox_56 = uicheckbox(app.EngineControllerPanel);
            app.CheckBox_56.Text = '1';
            app.LoadCellsLabel_2 = uilabel(app.EngineControllerPanel);
            app.LoadCellsLabel_2.Text = 'Load Cells';
            app.RunButton = uibutton(app.PlotsTab, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Visible = 'off';
            app.RunButton.Text = 'Run';
            app.SavePlotsButton = uibutton(app.PlotsTab, 'push');
            app.SavePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @savePlots, true);
            app.SavePlotsButton.Visible = 'off';
            app.SavePlotsButton.Text = 'Save Plots';
            app.CopyplotButton = uibutton(app.PlotsTab, 'push');
            app.CopyplotButton.ButtonPushedFcn = createCallbackFcn(app, @copyPlots, true);
            app.CopyplotButton.Visible = 'off';
            app.CopyplotButton.Text = 'Copy plot';
            app.closeallButton = uibutton(app.PlotsTab, 'push');
            app.closeallButton.ButtonPushedFcn = createCallbackFcn(app, @closeall, true);
            app.closeallButton.Visible = 'off';
            app.closeallButton.Text = 'close all';
            app.NewFigureEditFieldLabel = uilabel(app.PlotsTab);
            app.NewFigureEditFieldLabel.Text = 'New Figure:';
            app.NewFigureEditField = uieditfield(app.PlotsTab, 'text');
            app.NewFigureEditField.ValueChangedFcn = createCallbackFcn(app, @NewFigureEditFieldValueChanged, true);
            app.NewFigureEditField.ValueChangingFcn = createCallbackFcn(app, @NewFigureEditFieldValueChanging, true);
            app.ListBoxLabel = uilabel(app.PlotsTab);
            app.ListBoxLabel.Text = 'List Box';
            app.ListBox = uilistbox(app.PlotsTab);
            app.ListBox.Items = {'Select Figure'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'Select Figure';
            app.ClosefigureButton = uibutton(app.PlotsTab, 'push');
            app.ClosefigureButton.ButtonPushedFcn = createCallbackFcn(app, @closeCurrentFigure, true);
            app.ClosefigureButton.Visible = 'off';
            app.ClosefigureButton.Text = 'Close figure';
            app.PostprocessingTab = uitab(app.TabGroup);
            app.PostprocessingTab.Title = 'Postprocessing';
            app.LegendCheckBox = uicheckbox(app.PostprocessingTab);
            app.LegendCheckBox.Text = 'Legend';
            app.ColorSchemeDropDownLabel = uilabel(app.PostprocessingTab);
            app.ColorSchemeDropDownLabel.Text = 'Color Scheme:';
            app.ColorSchemeDropDown = uidropdown(app.PostprocessingTab);
            app.ColorSchemeDropDown.Items = {'Select One', 'Jet', 'Colorful'};
            app.ColorSchemeDropDown.Value = 'Select One';
            app.LineThicknessEditFieldLabel = uilabel(app.PostprocessingTab);
            app.LineThicknessEditFieldLabel.Text = 'Line Thickness:';
            app.LineThicknessEditField = uieditfield(app.PostprocessingTab, 'text');
            app.LineSymbolsEditFieldLabel = uilabel(app.PostprocessingTab);
            app.LineSymbolsEditFieldLabel.Text = 'Line Symbols:';
            app.LineSymbolsEditField = uieditfield(app.PostprocessingTab, 'text');
            app.TitleEditFieldLabel = uilabel(app.PostprocessingTab);
            app.TitleEditFieldLabel.Text = 'Title:';
            app.TitleEditField = uieditfield(app.PostprocessingTab, 'text');
            app.TitleEditField.ValueChangingFcn = createCallbackFcn(app, @TitleEditFieldValueChanging, true);
            app.xlabelEditFieldLabel = uilabel(app.PostprocessingTab);
            app.xlabelEditFieldLabel.Text = 'xlabel:';
            app.xlabelEditField = uieditfield(app.PostprocessingTab, 'text');
            app.ylabelEditFieldLabel = uilabel(app.PostprocessingTab);
            app.ylabelEditFieldLabel.Text = 'ylabel:';
            app.ylabelEditField = uieditfield(app.PostprocessingTab, 'text');
            app.ylimEditFieldLabel = uilabel(app.PostprocessingTab);
            app.ylimEditFieldLabel.Text = 'ylim:';
            app.ylimEditField = uieditfield(app.PostprocessingTab, 'text');
            app.xlimEditFieldLabel = uilabel(app.PostprocessingTab);
            app.xlimEditFieldLabel.Text = 'xlim:';
            app.xlimEditField = uieditfield(app.PostprocessingTab, 'text');
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.ECPTprefixEditFieldLabel = uilabel(app.SettingsTab);
            app.ECPTprefixEditFieldLabel.Text = 'EC PT prefix';
            app.ECPTprefixEditField = uieditfield(app.SettingsTab, 'text');
            app.ECPTprefixEditField.ValueChangedFcn = createCallbackFcn(app, @ECPTprefixEditFieldValueChanged, true);
            app.ECPTprefixEditField.Value = 'press_pressure_';
            app.GSEControllerPTprefixEditFieldLabel = uilabel(app.SettingsTab);
            app.GSEControllerPTprefixEditFieldLabel.Text = 'GSE Controller PT prefix';
            app.GSEControllerPTprefixEditField = uieditfield(app.SettingsTab, 'text');
            app.GSEControllerPTprefixEditField.Value = 'gse_pressure_';
            app.GSEControllerPTsuffixEditFieldLabel = uilabel(app.SettingsTab);
            app.GSEControllerPTsuffixEditFieldLabel.Text = 'GSE Controller PT suffix';
            app.GSEControllerPTsuffixEditField = uieditfield(app.SettingsTab, 'text');
            app.GSEControllerPTsuffixEditField.Value = '__psi_';
            app.ECPTsuffixEditFieldLabel = uilabel(app.SettingsTab);
            app.ECPTsuffixEditFieldLabel.Text = 'EC PT suffix';
            app.ECPTsuffixEditField = uieditfield(app.SettingsTab, 'text');
            app.ECPTsuffixEditField.ValueChangedFcn = createCallbackFcn(app, @ECPTsuffixEditFieldValueChanged, true);
            app.ECPTsuffixEditField.Value = '__psi_';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startApp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end